classdef gengqi0107 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnalysisPanel                  matlab.ui.container.Panel
        ShowKeyMetricsButton           matlab.ui.control.Button
        ShowEVMButton                  matlab.ui.control.Button
        ShowChannelRankButton          matlab.ui.control.Button
        ShowSynchronizationButton      matlab.ui.control.Button
        ShowChannelSymbolsButton       matlab.ui.control.Button
        ShowCIRCTFButton               matlab.ui.control.Button
        ShowRxSignalButton             matlab.ui.control.Button
        ReceivePanel                   matlab.ui.container.Panel
        ReceivedPreviewTextArea        matlab.ui.control.TextArea
        ReceivedfilesLabel             matlab.ui.control.Label
        BERValueLabel                  matlab.ui.control.Label
        BERLabel                       matlab.ui.control.Label
        BitErrorCountValueLabel        matlab.ui.control.Label
        BitErrorCountLabel             matlab.ui.control.Label
        StartProcessingButton          matlab.ui.control.Button
        DataSelectionPanel             matlab.ui.container.Panel
        RecommendedBlocksandSubBlocksLabel  matlab.ui.control.Label
        DataPreviewTextArea            matlab.ui.control.TextArea
        SentfilesLabel                 matlab.ui.control.Label
        StartTransmissionButton        matlab.ui.control.Button
        TransmissionStatusLabel_2      matlab.ui.control.Label
        TransmissionStatusLabel        matlab.ui.control.Label
        FileTypeLabel                  matlab.ui.control.Label
        FileTypeisLabel                matlab.ui.control.Label
        FilePathEditField              matlab.ui.control.EditField
        FilePathLabel                  matlab.ui.control.Label
        SelectFileButton               matlab.ui.control.Button
        CodingMIMOPanel                matlab.ui.container.Panel
        ChannelEqualizerDropDown       matlab.ui.control.DropDown
        ChannelEqualizerDropDownLabel  matlab.ui.control.Label
        ChannelEstimatorDropDown       matlab.ui.control.DropDown
        ChannelEstimatorLabel          matlab.ui.control.Label
        MIMOSchemeDropDown             matlab.ui.control.DropDown
        MIMOSchemeLabel                matlab.ui.control.Label
        ChannelCodingDropDown          matlab.ui.control.DropDown
        ChannelCodingLabel             matlab.ui.control.Label
        SystemParametersPanel          matlab.ui.container.Panel
        BasebandFrequencyEditField     matlab.ui.control.NumericEditField
        BasebandFrequencyEditFieldLabel  matlab.ui.control.Label
        DauerderbertragungEditField    matlab.ui.control.NumericEditField
        DauerderbertragungEditFieldLabel  matlab.ui.control.Label
        AnzahlderSubBlckeEditField     matlab.ui.control.NumericEditField
        AnzahlderSubBlckeEditFieldLabel  matlab.ui.control.Label
        AnzahlderBlckeEditField        matlab.ui.control.NumericEditField
        AnzahlderBlckeEditFieldLabel   matlab.ui.control.Label
        DACSamplingRateEditField       matlab.ui.control.NumericEditField
        DACSamplingRateLabel           matlab.ui.control.Label
        ModulationOrderDropDown        matlab.ui.control.DropDown
        ModulationOrderLabel           matlab.ui.control.Label
        CarrierFrequencyEditField      matlab.ui.control.NumericEditField
        CarrierFrequencyLabel          matlab.ui.control.Label
        RxAntennasEditField            matlab.ui.control.NumericEditField
        RxAntennasLabel                matlab.ui.control.Label
        TxAntennasEditField            matlab.ui.control.NumericEditField
        TxAntennasLabel                matlab.ui.control.Label
        CPLengthEditField              matlab.ui.control.NumericEditField
        CPLengthLabel                  matlab.ui.control.Label
        FFTLengthDropDown              matlab.ui.control.DropDown
        FFTLengthLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        iNfft            (1,1) double = 128      % FFT length
        iNg              (1,1) double = 32       % CP length
        iModOrd          (1,1) double = 1        % Bits per subcarrier (BPSK=1, 4QAM=2, 8QAM=3, 16QAM=4, 32QAM=5, 64QAM=6)
        iNoTxAnt         (1,1) double = 4       % Number of Tx antennas
        iNoRxAnt         (1,1) double = 8        % Number of Rx antennas
        fCarrFreq        (1,1) double = 8000     % Carrier frequency
        fBBFreq          (1,1) double = 8000     % Baseband frequency
        fDACFreq         (1,1) double = 48000    % DAC sampling rate
        mimoMode         char = 'Spatial Multiplexing'      % MIMO mode
        codingMode       char = 'Keine'          % Channel coding mode
        chEstimator      char = 'Zero Forcing'   % Channel estimator
        equalizerMode    char = 'MMSE'           % Equalizer mode
        
        % EigenMode-related data
        V1               % Precoding matrix [Nt x Nt x Nfft]
        U1               % Left singular vectors
        S1               % Singular values
        EigenMeta        % Debug meta, e.g., channel response etc.

        % Tx/Rx related parameters
        lenBitsData      (1,1) double = 0        % Payload bits (estimated from file bytes * 8)
        iNoBlocks        (1,1) double = 1        % Recommended number of blocks
        anzGesBits       (1,1) double = 0        % Total coded bits
        iNoSubBlocks     (1,1) double = 4 
        recBlocksFixed   (1,1) double = NaN;   % 第一次推荐的 blocks（冻结显示）
        recSubFixed      (1,1) double = NaN;   % 第一次推荐的 sub-blocks（冻结显示）
        hasRecommendation (1,1) logical = false; % 是否已经生成过推荐（针对当前文件）

        % Data buffers
        SendeDatei                                % Original file content (text / image)
        DatenTyp          char = ''               % 'Text' or 'Image'
        TxSequence                                % Tx time-domain sequence
        OriginalBits                              % Original bits for BER display
        Filename           char = ''              % Selected file name
        TxMeta                                     % Tx meta info (number of frames, duration, etc.)
        RxSequence                                % Received signal [Nsamples x iNoRxAnt]
        RxAnalysisData                            % Last processing result from Signalverarbeitung_app
        TxLoopAnalysisData                        % Analysis result when processing Tx directly (ideal loopback)
    end
    
    methods (Access = private)
        %  Generic text preview
        function showTextPreviewGeneric(~, targetTextArea, text_data, ~)
            if ~isempty(targetTextArea) && isvalid(targetTextArea)
                targetTextArea.Visible = 'on';
                uistack(targetTextArea,'top');
                targetTextArea.Value = splitlines(string(text_data));
            end
        end

        %  Compute recommended number of blocks and total coded bits
        function [optiNoBlock, anzGesBits] = calculateOptimalBlocks(app, lenBits_data)
            % Coded bits (following old logic)
            switch string(app.codingMode)
                case "Keine"
                    anzBits = lenBits_data;
                otherwise
                    anzBits = lenBits_data;
            end
            anzGesBits = anzBits;

            % Legacy formula for recommended blocks (includes 136*iModOrd header overhead)
            capacity_per_symbol = app.iModOrd * app.iNoTxAnt * app.iNfft;
            optiNoBlock = ceil((anzGesBits + 136*app.iModOrd) / max(capacity_per_symbol,1));
        end

        % Update recommended blocks after parameter changes
        function updateRecommendedBlocks(app)
            % 1) 无文件：不推荐
            if app.lenBitsData <= 0
                app.hasRecommendation = false;
                app.recBlocksFixed = NaN;
                app.recSubFixed    = NaN;
        
                if isvalid(app.RecommendedBlocksandSubBlocksLabel)
                    app.RecommendedBlocksandSubBlocksLabel.Text = 'No file loaded';
                end
                return;
            end
        
            % 2) 已经推荐过：不再更新推荐显示（保持第一次推荐不变）
            if app.hasRecommendation && isfinite(app.recBlocksFixed) && isfinite(app.recSubFixed)
                % 只做一个安全性修正：保证当前使用值仍合法（可选）
                % （建议不在这里强行改用户输入，只在 ValueChanged 里检查）
                return;
            end
        
            % 3) 第一次推荐：计算并冻结
            [recBlocks, app.anzGesBits] = app.calculateOptimalBlocks(app.lenBitsData);
        
            % 用当前 recBlocks 推导 subblocks（注意：suggestSubBlocks 用到了 app.iNoBlocks）
            oldBlocks = app.iNoBlocks;
            app.iNoBlocks = recBlocks;
            recSub = app.suggestSubBlocks();
            app.iNoBlocks = oldBlocks;
        
            % 冻结推荐值
            app.recBlocksFixed = recBlocks;
            app.recSubFixed    = recSub;
            app.hasRecommendation = true;
        
            % 4) 只在第一次推荐时：初始化 UI 当前使用值（让用户从推荐值开始）
            app.iNoBlocks = recBlocks;
            if isvalid(app.AnzahlderBlckeEditField)
                app.AnzahlderBlckeEditField.Value = app.iNoBlocks;
            end
        
            app.iNoSubBlocks = recSub;
            if isvalid(app.AnzahlderSubBlckeEditField)
                app.AnzahlderSubBlckeEditField.Value = app.iNoSubBlocks;
            end
        
            % 5) 更新 RecommendedBlocksandSubBlocksLabel（永远显示冻结推荐值）
            if isvalid(app.RecommendedBlocksandSubBlocksLabel)
                app.RecommendedBlocksandSubBlocksLabel.Text = sprintf( ...
                    'Recommended: Blocks = %d, Sub-blocks = %d', ...
                    app.recBlocksFixed, app.recSubFixed);
            end
        end

        function iSub = suggestSubBlocks(app)
            % Auto suggestion of iNoSubBlocks based on pilot overhead
            targetPilotOverhead = 0.12;   % target pilot overhead ~12%
            
            % Simple heuristic: derive from number of Tx antennas and desired overhead
            ideal = app.iNoTxAnt * (1/targetPilotOverhead - 1);
            
            % Clamp to a reasonable range
            iMin = 2;
            iMax = 20;
            
            iSub = round(min(iMax, max(iMin, ideal)));
            
            % Ensure at least one subframe exists
            if app.iNoBlocks < iSub
                iSub = max(1, app.iNoBlocks);
            end
        end

        %  Map modulation string to bits per symbol
        function bits = mapModToBits(~, modStr)
            s = lower(string(modStr));
            switch s
                case "bpsk",  bits = 1;
                case {"4qam","qpsk"}, bits = 2;
                case "8qam",  bits = 3;
                case "16qam", bits = 4;
                case "32qam", bits = 5;
                case "64qam", bits = 6;
                otherwise,    bits = 2; % default QPSK
            end
        end
        
        % Build parameter struct for Tx generation
        function params = buildTxParams(app)
            params = struct( ...
                'iNoBlocks',    app.iNoBlocks, ...
                'iNfft',        app.iNfft, ...
                'iNg',          app.iNg, ...
                'iNb',          app.iNfft + app.iNg, ...
                'iModOrd',      app.iModOrd, ...
                'iNoTxAnt',     app.iNoTxAnt, ...
                'iNoRxAnt',     app.iNoRxAnt, ...
                'iNoSubBlocks', app.iNoSubBlocks, ...
                'fBBFreq',      app.fBBFreq, ...
                'fDACFreq',     app.fDACFreq, ...
                'fCarrFreq',    app.fCarrFreq, ...
                'mimoMode',     app.mimoMode, ...
                'codingMode',   app.codingMode ...
            );
        end

        % Wrapper for EigenMode preamble generation (for channel sounding)
        function [txPreCar, metaPre, paramsPre] = callGenerateEigenModePreamble(app)
            % txPreCar : upconverted preamble signal [Nsamples x iNoTxAnt]
            % metaPre  : meta returned by generateEigenModePreamble_app
            % paramsPre: parameters used for preamble generation
        
            paramsPre = app.buildTxParams();
        
            % Optional: allow dedicated number of preambles Npre
            if isprop(app, 'Npre') && ~isempty(app.Npre)
                paramsPre.Npre = app.Npre;
            else
                paramsPre.Npre = 1;
            end
        
            % Only a subset of fields is really used by generateEigenModePreamble_app,
            % but using a unified struct keeps things consistent.
            [txPreCar, metaPre] = generateEigenModePreamble_app(paramsPre);
        end

        % Wrapper for generic Tx sequence generation
        function [mFrameTxCar, meta] = callGenerateTx(app)
            params = app.buildTxParams();

            % In EigenMode: if V1 is already measured, pass it to Tx generator
            mimoModeStr = string(params.mimoMode);
            if strcmpi(mimoModeStr, 'EigenMode')
                if isprop(app, 'V1') && ~isempty(app.V1)
                    params.V1 = app.V1;
                else
                    % Only warn here, let generateTxSequence_app decide how to handle
                    warning('callGenerateTx: EigenMode mode but app.V1 is empty, please run channel sounding first.');
                end
            end
            source = struct( ...
                'DatenTyp',   app.DatenTyp, ...
                'SendeDatei', app.SendeDatei ...
            );
        
            [mFrameTxCar, meta] = generateTxSequence_app(params, source);
        end  

        function X = localNormalizeChan4D(~, X)
            if ndims(X) ~= 4
                return;
            end
            sz = size(X);
            c = sz(3); d = sz(4);
        
            % heuristic: Nr(=8) >= Nt(=4), if dim3 < dim4 then swap
            if c < d
                X = permute(X, [1 2 4 3]);
            end
        end
        
        function sf = localSelectSubframe(app, nSF, titleStr)
            if nSF <= 1
                sf = 1;
                return;
            end
        
            opts = compose("Subframe %d", 1:nSF);
            choice = uiconfirm(app.UIFigure, ...
                'Choose which subframe to visualize:', ...
                titleStr, ...
                'Options', [opts, "Cancel"], ...
                'DefaultOption', opts(1), ...
                'CancelOption', 'Cancel');
        
            if isempty(choice) || strcmp(choice,'Cancel')
                sf = [];
                return;
            end
        
            tok = regexp(choice, '\d+', 'match', 'once');
            sf = str2double(tok);
            if ~isfinite(sf) || sf < 1 || sf > nSF
                sf = 1;
            end
        end
        function v = getField(~, s, fn)
            if isfield(s,fn) && ~isempty(s.(fn))
                v = s.(fn);
            else
                v = [];
            end
        end
        
        function rows = addKV(~, rows, k, v)
            if isempty(v)
                rows(end+1,:) = {char(k), ''}; 
            else
                if isnumeric(v) && isscalar(v)
                    rows(end+1,:) = {char(k), sprintf('%.6g', v)}; 
                elseif isnumeric(v) && ~isscalar(v)
                    rows(end+1,:) = {char(k), sprintf('[%s]', sprintf('%g ', v(1:min(end,5))))}; 
                else
                    rows(end+1,:) = {char(k), char(string(v))}; 
                end
            end
        end
       
        function deviceName = getAudioDeviceName(app)
            if ismac()
                deviceName = 'Scarlett 18i20 USB';
            else
                deviceName = 'Focusrite USB ASIO';
            end
        end
        
        function setStatus(app, msg)
            if isprop(app,'TransmissionStatusLabel_2') && isvalid(app.TransmissionStatusLabel_2)
                app.TransmissionStatusLabel_2.Text = msg;
            elseif isprop(app,'TransmissionStatusValueLabel') && isvalid(app.TransmissionStatusValueLabel)
                app.TransmissionStatusValueLabel.Text = msg;
            end
            drawnow;
        end
        
        function procParam = buildProcParam(app, rxSignal)
            iNb = app.iNfft + app.iNg;
        
            if isstruct(app.TxMeta) && isfield(app.TxMeta,'iNewNoBlocks') && ~isempty(app.TxMeta.iNewNoBlocks)
                iNewNoBlocks = app.TxMeta.iNewNoBlocks;
            else
                iNewNoBlocks = app.iNoBlocks;
            end
        
            if isstruct(app.TxMeta) && isfield(app.TxMeta,'len_cInfoBits') && ~isempty(app.TxMeta.len_cInfoBits)
                len_cInfoBits = app.TxMeta.len_cInfoBits;
            else
                len_cInfoBits = 0;
            end
        
            if isstruct(app.TxMeta) && isfield(app.TxMeta,'origTxtLenBits') && ~isempty(app.TxMeta.origTxtLenBits)
                origTxtLenBits = app.TxMeta.origTxtLenBits;
            else
                origTxtLenBits = [];
            end
        
            procParam = struct( ...
                'rxSignal',         rxSignal, ...
                'fs',               app.fDACFreq, ...
                'iNfft',            app.iNfft, ...
                'iNg',              app.iNg, ...
                'iNb',              iNb, ...
                'iNoBlocks',        app.iNoBlocks, ...
                'iNoTxAnt',         app.iNoTxAnt, ...
                'iNoRxAnt',         app.iNoRxAnt, ...
                'iNoSubBlocks',     app.iNoSubBlocks, ...
                'iNewNoBlocks',     iNewNoBlocks, ...
                'iModOrd',          app.iModOrd, ...
                'fBBFreq',          app.fBBFreq, ...
                'fCarrFreq',        app.fCarrFreq, ...
                'mimoMode',         app.mimoMode, ...
                'codingMode',       app.codingMode, ...
                'channelEstimator', app.chEstimator, ...
                'equalizerMode',    app.equalizerMode, ...
                'len_cInfoBits',    len_cInfoBits, ...
                'origTxtLenBits',   origTxtLenBits, ...
                'DatenTyp',         app.DatenTyp, ...
                'SendeDatei',       app.SendeDatei);
        
            if strcmpi(strtrim(lower(string(procParam.mimoMode))), 'eigenmode')
                if isempty(app.V1)
                    error('EigenMode precoder V1 is missing.');
                end
                procParam.V1 = app.V1;
                if ~isempty(app.U1),        procParam.U1 = app.U1; end
                if ~isempty(app.S1),        procParam.S1 = app.S1; end
                if ~isempty(app.EigenMeta), procParam.EigenMeta = app.EigenMeta; end
            end
        end
        
        function ok = processRx(app, showAlerts)
            ok = false;
        
            if isempty(app.RxSequence)
                if showAlerts
                    uialert(app.UIFigure, ...
                        'No received signal available. Run a transmission first.', ...
                        'No Rx signal');
                end
                return;
            end
        
            app.setStatus('Processing received signal...');
        
            try
                rxSignal = app.RxSequence.'; % [Nsamp x Nr]
                procParam = app.buildProcParam(rxSignal);
        
                data = Signalverarbeitung_app(procParam);
                app.RxAnalysisData = data;
        
                try
                    outDir = 'Rx-Signale';
                    if ~exist(outDir,'dir'), mkdir(outDir); end
                    save(fullfile(outDir,'data.mat'), 'data');
                catch
                end
        
                app.ReceivedPreviewTextArea.Value = {''};
        
                if isfield(data,'text') && ~isempty(data.text)
                    app.showTextPreviewGeneric(app.ReceivedPreviewTextArea, data.text, 'Recovered text');
                else
                    app.showTextPreviewGeneric(app.ReceivedPreviewTextArea, '(No decoded text)', '');
                end
        
                if isfield(data,'numBitError') && ~isempty(data.numBitError)
                    app.BitErrorCountValueLabel.Text = num2str(data.numBitError);
                else
                    app.BitErrorCountValueLabel.Text = '-';
                end
        
                if isfield(data,'bitErrorRate') && ~isempty(data.bitErrorRate)
                    app.BERValueLabel.Text = sprintf('%.4e', data.bitErrorRate);
                else
                    app.BERValueLabel.Text = '-';
                end
        
                app.setStatus('Receive processing finished.');
                ok = true;
        
            catch ME
                fprintf(1, '\n================ MATLAB ERROR REPORT ================\n');
                fprintf(1, '%s\n', getReport(ME, 'extended', 'hyperlinks', 'off'));
                fprintf(1, '=====================================================\n\n');
        
                if showAlerts
                    uialert(app.UIFigure, ['Signal processing failed: ' ME.message], 'Processing error');
                end
                app.setStatus('Processing failed.');
            end
        end
        
        function updateTxDurationSeconds(app, nSamplesTotal)
        % Update transmission duration in seconds.
        
            fs = app.fDACFreq;
        
            if ~isfinite(fs) || fs <= 0 || ~isfinite(nSamplesTotal) || nSamplesTotal <= 0
                if isvalid(app.DauerderbertragungEditField)
                    app.DauerderbertragungEditField.Value = NaN;
                end
                return;
            end
        
            if isvalid(app.DauerderbertragungEditField)
                app.DauerderbertragungEditField.Value = double(nSamplesTotal) / double(fs);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.ModulationOrderDropDown.Items = {'BPSK','4QAM','8QAM','16QAM','32QAM','64QAM'};
            fftList = [8 16 32 64 128 256 512 1024];
            app.FFTLengthDropDown.Items = cellstr(string(fftList));
            app.FFTLengthDropDown.Value = '128';

            app.ChannelCodingDropDown.Items = {'Keine'};
            app.MIMOSchemeDropDown.Items    = {'EigenMode','Spatial Multiplexing','Alamouti','V-BLAST'};
            app.ChannelEstimatorDropDown.Items = {'Zero Forcing'};
            app.ChannelEqualizerDropDown.Items     = {'MMSE'};

            % UI -> internal properties
            app.iNfft   = 128;
            app.iNg     = 32;
            app.iModOrd = 1;
            app.iNoTxAnt= 4;
            app.iNoRxAnt= 8;
            app.fCarrFreq=8000;
            app.fBBFreq=8000;
            app.fDACFreq =48000;
            app.codingMode='Keine';
            app.mimoMode  ='Spatial Multiplexing';
            app.iNoSubBlocks = 8;
            app.AnzahlderSubBlckeEditField.Value = app.iNoSubBlocks;

            % Sync to widgets
            app.CPLengthEditField.Value = app.iNg;
            app.TxAntennasEditField.Value = app.iNoTxAnt;
            app.RxAntennasEditField.Value = app.iNoRxAnt;
            app.CarrierFrequencyEditField.Value = app.fCarrFreq;
            app.BasebandFrequencyEditField.Value = app.fBBFreq;
            app.DACSamplingRateEditField.Value = app.fDACFreq;
            app.ModulationOrderDropDown.Value = 'BPSK';
            app.FFTLengthDropDown.Value = '128';
            app.ChannelCodingDropDown.Value = 'Keine';
            app.MIMOSchemeDropDown.Value    = 'Spatial Multiplexing';
            app.ChannelEstimatorDropDown.Value = 'Zero Forcing';
            app.ChannelEqualizerDropDown.Value     = 'MMSE';

            % Preview areas: hide text by default
            app.DataPreviewTextArea.Visible = 'on';
            app.ReceivedPreviewTextArea.Visible = 'on';

            app.TransmissionStatusLabel_2.Text = 'No file loaded';
           
          
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
     
            [filename, pathname] = uigetfile({'*.txt;*.TXT'}, 'Select file to transmit');
            if isequal(filename,0)
                return; 
            end
            file = fullfile(pathname, filename);
            app.FilePathEditField.Value = file;
            app.Filename = filename;

            % Clear display
            app.DataPreviewTextArea.Value = {''};
            
            try
                text_data = fileread(file);
            catch ME
                uialert(app.UIFigure, ['Unable to read file: ' ME.message], 'File error');
                return;
            end
            app.SendeDatei = text_data;
            app.DatenTyp   = 'Text';
            app.FileTypeLabel.Text = 'Text (TXT)';
            app.showTextPreviewGeneric(app.DataPreviewTextArea, text_data, 'Original text');
      

            % Estimate payload bits from file size
            info = dir(file);
            app.lenBitsData = info.bytes*8;
            app.hasRecommendation = false;
            app.recBlocksFixed = NaN;
            app.recSubFixed    = NaN;
            app.updateRecommendedBlocks();
        end

        % Button pushed function: StartTransmissionButton
        function StartTransmissionButtonPushed(app, event)
            if isempty(app.SendeDatei)
                uialert(app.UIFigure, 'Please select a file first!', 'File missing');
                return;
            end
        
            frameLen = 512;
            totalTxSamples = 0;
        
            try
                % -------- EigenMode sounding --------
                if strcmpi(app.mimoMode, 'EigenMode')
                    [txPreCar, metaPre, paramsPre] = app.callGenerateEigenModePreamble();
        
                    % Accumulate preamble samples
                    totalTxSamples = totalTxSamples + size(txPreCar, 1);
        
                    deviceName = app.getAudioDeviceName();
                    [rxPre_raw, ~] = runScarlettMimoIO( ...
                        txPreCar, ...
                        app.fDACFreq, ...
                        app.iNoTxAnt, ...
                        app.iNoRxAnt, ...
                        deviceName, ...
                        frameLen);
        
                    rxPre_forEst = rxPre_raw.';
                    [U1, S1, V1, chanMeta] = EigenMode_ChanEst_app(rxPre_forEst, paramsPre, metaPre);
        
                    app.V1        = V1;
                    app.U1        = U1;
                    app.S1        = S1;
                    app.EigenMeta = chanMeta;
                end
        
                % -------- Payload transmission --------
                [txSig, meta] = app.callGenerateTx();
                app.TxSequence = txSig.';
                app.TxMeta     = meta;
        
                % Accumulate payload samples
                totalTxSamples = totalTxSamples + size(txSig, 1);
        
                deviceName = app.getAudioDeviceName();
                [rx_raw, usedRealDevice] = runScarlettMimoIO( ...
                    txSig, ...
                    app.fDACFreq, ...
                    app.iNoTxAnt, ...
                    app.iNoRxAnt, ...
                    deviceName, ...
                    frameLen);
        
                app.RxSequence = rx_raw.';
        
                % -------- Update transmission duration (seconds) --------
                app.updateTxDurationSeconds(totalTxSamples);
        
                % -------- Status text --------
                if usedRealDevice
                    app.TransmissionStatusLabel_2.Text = sprintf( ...
                        'Tx/Rx done: Tx %d, Rx %d, %d samples.', ...
                        app.iNoTxAnt, app.iNoRxAnt, size(rx_raw,1));
                else
                    app.TransmissionStatusLabel_2.Text = sprintf( ...
                        'Device not available. Ideal loopback: %d samples.', ...
                        size(rx_raw,1));
                end
        
                % -------- Automatic RX processing --------
                %app.processRx(false);
        
            catch ME
                fprintf(1, '\n================ MATLAB ERROR REPORT ================\n');
                fprintf(1, '%s\n', getReport(ME, 'extended', 'hyperlinks', 'off'));
                fprintf(1, '=====================================================\n\n');
        
                uialert(app.UIFigure, ['Transmission failed: ' ME.message], 'Error');
                app.TransmissionStatusLabel_2.Text = 'Transmission failed.';
            end
        
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
             app.processRx(true);
        end

        % Value changed function: FFTLengthDropDown
        function FFTLengthDropDownValueChanged(app, event)
            app.iNfft = str2double(event.Value);
            app.updateRecommendedBlocks();
        end

        % Value changed function: CPLengthEditField
        function CPLengthEditFieldValueChanged(app, event)
            % Read user input for new CP length
            newValue = event.Value;
        
            % Basic validity check
            if isnan(newValue) || newValue < 0
                uialert(app.UIFigure, 'CP length must be a non-negative number.', 'Input error');
                % Restore old value
                app.CPLengthEditField.Value = num2str(app.iNg);
                return;
            end
        
            % Update global parameter (integer number of samples)
            app.iNg = round(newValue);
            app.CPLengthEditField.Value = app.iNg;
        end

        % Value changed function: TxAntennasEditField
        function TxAntennasEditFieldValueChanged(app, event)
            value = app.TxAntennasEditField.Value;
        
            % Validate Tx antenna number: must be an integer in [1, 4]
            if isempty(value) || ~isfinite(value) || mod(value,1) ~= 0 || value < 1 || value > 4
                uialert(app.UIFigure, ...
                    'Tx antenna number must be an integer between 1 and 4.', ...
                    'Invalid Parameter');
        
                % Restore previous valid value
                app.TxAntennasEditField.Value = app.iNoTxAnt;
                return;
            end
        
            % Apply (store as integer)
            value = round(value);
        
            % If unchanged, do nothing
            if value == app.iNoTxAnt
                return;
            end
        
            % Update internal parameter
            app.iNoTxAnt = value;
            app.TxAntennasEditField.Value = app.iNoTxAnt;
        
            % Changing antenna numbers invalidates EigenMode SVD results
            app.V1 = [];
            app.U1 = [];
            app.S1 = [];
            app.EigenMeta = [];
        
            % Update dependent recommendations (blocks/sub-blocks)
            app.updateRecommendedBlocks();
        
            % Optional UI status message (uses existing label in your app)
            if isvalid(app.TransmissionStatusLabel_2)
                app.TransmissionStatusLabel_2.Text = sprintf('Tx antennas set to %d.', app.iNoTxAnt);
            end
        end

        % Value changed function: RxAntennasEditField
        function RxAntennasEditFieldValueChanged(app, event)
            value = app.RxAntennasEditField.Value;
        
            % Validate Rx antenna number: must be an integer in [1, 8]
            if isempty(value) || ~isfinite(value) || mod(value,1) ~= 0 || value < 1 || value > 8
                uialert(app.UIFigure, ...
                    'Rx antenna number must be an integer between 1 and 8.', ...
                    'Invalid Parameter');
        
                % Restore previous valid value
                app.RxAntennasEditField.Value = app.iNoRxAnt;
                return;
            end
        
            % Apply (store as integer)
            value = round(value);
        
            % If unchanged, do nothing
            if value == app.iNoRxAnt
                return;
            end
        
            % Update internal parameter
            app.iNoRxAnt = value;
            app.RxAntennasEditField.Value = app.iNoRxAnt;
        
            % Changing antenna numbers invalidates EigenMode SVD results
            app.V1 = [];
            app.U1 = [];
            app.S1 = [];
            app.EigenMeta = [];
        
            % Update dependent recommendations (blocks/sub-blocks)
            app.updateRecommendedBlocks();
        
            % Optional UI status message
            if isvalid(app.TransmissionStatusLabel_2)
                app.TransmissionStatusLabel_2.Text = sprintf('Rx antennas set to %d.', app.iNoRxAnt);
            end
        end

        % Value changed function: ModulationOrderDropDown
        function ModulationOrderDropDownValueChanged(app, event)
            % Update bits per symbol and recompute recommended blocks
            app.iModOrd = app.mapModToBits(event.Value);
            app.updateRecommendedBlocks();

        end

        % Value changed function: AnzahlderBlckeEditField
        function AnzahlderBlckeEditFieldValueChanged(app, event)
           newValue = app.AnzahlderBlckeEditField.Value;
        
            % 没推荐过/没文件：直接拒绝（避免奇怪状态）
            if ~app.hasRecommendation
                uialert(app.UIFigure, 'Please load a text file first.', 'No file');
                % 回滚到当前内部值
                app.AnzahlderBlckeEditField.Value = app.iNoBlocks;
                return;
            end
        
            % 必须是正整数
            if isempty(newValue) || ~isfinite(newValue) || newValue < 1
                uialert(app.UIFigure, 'Number of blocks must be a positive integer.', 'Input Error');
                app.AnzahlderBlckeEditField.Value = app.iNoBlocks;
                return;
            end
            newValue = round(newValue);
        
            % 合法性：Blocks >= SubBlocks（否则子帧无法分配）
            if newValue < app.iNoSubBlocks
                uialert(app.UIFigure, ...
                    sprintf('Blocks (%d) cannot be smaller than Sub-blocks (%d).', newValue, app.iNoSubBlocks), ...
                    'Input Conflict');
                app.AnzahlderBlckeEditField.Value = app.iNoBlocks;
                return;
            end
        
            % 接受用户输入（不触碰 RecommendedBlocksandSubBlocksLabel）
            app.iNoBlocks = newValue;
            app.AnzahlderBlckeEditField.Value = app.iNoBlocks;
        end

        % Value changed function: AnzahlderSubBlckeEditField
        function AnzahlderSubBlckeEditFieldValueChanged(app, event)
            newValue = app.AnzahlderSubBlckeEditField.Value;

            if ~app.hasRecommendation
                uialert(app.UIFigure, 'Please load a text file first.', 'No file');
                app.AnzahlderSubBlckeEditField.Value = app.iNoSubBlocks;
                return;
            end
        
            if isempty(newValue) || ~isfinite(newValue) || newValue < 1
                uialert(app.UIFigure, 'Number of sub-blocks must be a positive integer.', 'Input Error');
                app.AnzahlderSubBlckeEditField.Value = app.iNoSubBlocks;
                return;
            end
            newValue = round(newValue);
        
            % 合法性：SubBlocks <= Blocks
            if newValue > app.iNoBlocks
                uialert(app.UIFigure, ...
                    sprintf('Sub-blocks (%d) cannot exceed Blocks (%d).', newValue, app.iNoBlocks), ...
                    'Input Conflict');
                app.AnzahlderSubBlckeEditField.Value = app.iNoSubBlocks;
                return;
            end
        
            app.iNoSubBlocks = newValue;
            app.AnzahlderSubBlckeEditField.Value = app.iNoSubBlocks;
        end

        % Value changed function: BasebandFrequencyEditField
        function BasebandFrequencyEditFieldValueChanged(app, event)
            % Validate and update baseband frequency
            newValue = event.Value;

            if isnan(newValue) || newValue <= 0
                uialert(app.UIFigure, 'Baseband frequency must be a positive value.', ...
                    'Input Error');
                return;
            end

            app.fBBFreq = newValue;
        end

        % Value changed function: CarrierFrequencyEditField
        function CarrierFrequencyEditFieldValueChanged(app, event)
           % Read new carrier frequency
            newValue = event.Value;
        
            % Basic validity check
            if isnan(newValue) || newValue < 0
                uialert(app.UIFigure, 'Carrier frequency must be a non-negative value (Hz).', 'Input error');
                app.CarrierFrequencyEditField.Value = num2str(app.fCarrFreq);
                return;
            end
        
            % Update app property
            app.fCarrFreq = newValue;
        end

        % Value changed function: DACSamplingRateEditField
        function DACSamplingRateEditFieldValueChanged(app, event)
             % Read new DAC sampling rate
            newValue = event.Value;
        
            % Basic validity check
            if isnan(newValue) || newValue <= 0
                uialert(app.UIFigure, 'DAC sampling rate must be positive (Hz).', 'Input error');
                app.DACSamplingRateEditField.Value = num2str(app.fDACFreq);
                return;
            end
        
            app.fDACFreq = newValue;
        end

        % Value changed function: MIMOSchemeDropDown
        function MIMOSchemeDropDownValueChanged(app, event)
            app.mimoMode = char(event.Value);
            
        end

        % Button pushed function: ShowRxSignalButton
        function ShowRxSignalButtonPushed(app, event)
           % Show Rx (and optional Tx) signals in time/frequency/baseband domain
            if isempty(app.RxSequence)
                uialert(app.UIFigure, ...
                    'No received signal available. Please run a transmission first.', ...
                    'No Rx Signal');
                return;
            end
        
            fs = app.fDACFreq;
        
            % RxSequence: [Nr x Ns_rx] -> rx: [Ns_rx x Nr]
            rxMat = app.RxSequence;
            [Nr, Ns_rx] = size(rxMat);
            rx = rxMat.';  
        
            % TxSequence optional: [Nt x Ns_tx] -> tx: [Ns_tx x Nt]
            tx = [];
            Nt = 0; Ns_tx = 0;
            if ~isempty(app.TxSequence)
                txMat = app.TxSequence;
                [Nt, Ns_tx] = size(txMat);
                tx = txMat.';
            end
        
            % ---------- create tabbed figure ----------
            fig = figure('Name','Rx/Tx Signal Viewer','NumberTitle','off');
            tg  = uitabgroup(fig);
        
            % ---------- Time Domain tabs ----------
            tabRxT = uitab(tg, 'Title', sprintf('Rx Time (%d mics)', Nr));
            axT = tiledlayout(tabRxT, Nr, 1, 'TileSpacing','compact', 'Padding','compact');
            t_rx = (0:Ns_rx-1)'/fs;
            for ch = 1:Nr
                nexttile(axT, ch);
                plot(t_rx, rx(:,ch));
                grid on;
                xlabel('Time (s)');
                ylabel(sprintf('Rx ch%d', ch));
                title(sprintf('Rx channel %d waveform', ch));
            end
        
            if Nt > 0
                tabTxT = uitab(tg, 'Title', sprintf('Tx Time (%d spk)', Nt));
                axTT = tiledlayout(tabTxT, Nt, 1, 'TileSpacing','compact', 'Padding','compact');
                t_tx = (0:Ns_tx-1)'/fs;
                for ch = 1:Nt
                    nexttile(axTT, ch);
                    plot(t_tx, tx(:,ch));
                    grid on;
                    xlabel('Time (s)');
                    ylabel(sprintf('Tx ch%d', ch));
                    title(sprintf('Tx channel %d waveform', ch));
                end
            end
        
            % ---------- Frequency Domain tabs ----------
            Nfft_spec = 2^nextpow2(max([Ns_rx, Ns_tx, 1])); % avoid 0
            f_axis = (-Nfft_spec/2:Nfft_spec/2-1)'/Nfft_spec * fs;  % Hz
        
            tabRxF = uitab(tg, 'Title', sprintf('Rx Spectrum (%d mics)', Nr));
            axRF = tiledlayout(tabRxF, Nr, 1, 'TileSpacing','compact', 'Padding','compact');
            for ch = 1:Nr
                X = fftshift(fft(rx(:,ch), Nfft_spec));
                mag = 20*log10(abs(X)+eps);
                nexttile(axRF, ch);
                plot(f_axis/1e3, mag);
                grid on;
                xlabel('Frequency (kHz)');
                ylabel('Magnitude (dB)');
                title(sprintf('Rx channel %d spectrum', ch));
            end
        
            if Nt > 0
                tabTxF = uitab(tg, 'Title', sprintf('Tx Spectrum (%d spk)', Nt));
                axTF = tiledlayout(tabTxF, Nt, 1, 'TileSpacing','compact', 'Padding','compact');
                for ch = 1:Nt
                    X = fftshift(fft(tx(:,ch), Nfft_spec));
                    mag = 20*log10(abs(X)+eps);
                    nexttile(axTF, ch);
                    plot(f_axis/1e3, mag);
                    grid on;
                    xlabel('Frequency (kHz)');
                    ylabel('Magnitude (dB)');
                    title(sprintf('Tx channel %d spectrum', ch));
                end
            end
        
            % ---------- Baseband Spectrum tab ----------
            fCarr = app.fCarrFreq;
            Bbb   = app.fBBFreq;
            if isempty(fCarr) || ~isfinite(fCarr) || isempty(Bbb) || ~isfinite(Bbb) || Bbb <= 0
               
                tabBB = uitab(tg, 'Title', 'Rx Baseband (invalid params)');
                uicontrol(tabBB, 'Style','text', 'Units','normalized', ...
                    'Position',[0.05 0.45 0.9 0.1], ...
                    'String','fCarrFreq / fBBFreq invalid -> cannot compute baseband spectrum.', ...
                    'HorizontalAlignment','left');
                return;
            end
        
            tabBB = uitab(tg, 'Title', sprintf('Rx Baseband (B=%.0fHz)', Bbb));
            axBB  = tiledlayout(tabBB, Nr, 1, 'TileSpacing','compact', 'Padding','compact');
        
            halfB = Bbb/2;
            for ch = 1:Nr
                xr = rx(:,ch);
                X_full = fftshift(fft(xr, Nfft_spec));
                mask = abs(f_axis - fCarr) <= halfB;
        
                nexttile(axBB, ch);
                if ~any(mask)
                    text(0.05,0.5, sprintf('Ch%d: no points in f=%.1f±%.1f Hz', ch, fCarr, halfB), ...
                        'Units','normalized');
                    axis off;
                    continue;
                end
        
                f_seg  = f_axis(mask);
                X_seg  = X_full(mask);
                f_bb   = f_seg - fCarr;
                mag_bb = 20*log10(abs(X_seg)+eps);
        
                plot(f_bb/1e3, mag_bb);
                grid on;
                xlabel('Baseband frequency (kHz)');
                ylabel('Magnitude (dB)');
                title(sprintf('Rx ch%d baseband (|f-fc|≤%.0fHz)', ch, halfB));
            end
        end

        % Button pushed function: ShowSynchronizationButton
        function ShowSynchronizationButtonPushed(app, event)
             if isempty(app.RxAnalysisData) || ~isfield(app.RxAnalysisData,'MetricData')
                uialert(app.UIFigure, ...
                    'No synchronization analysis available. Please run Rx processing first.', ...
                    'No Sync Data');
                return;
            end

            MetricDataCell = app.RxAnalysisData.MetricData;
            if isempty(MetricDataCell)
                uialert(app.UIFigure, ...
                    'MetricData is empty.', ...
                    'No Sync Data');
                return;
            end

            Nr = numel(MetricDataCell);

            fig = figure('Name','Rahmensynchronisation pro Mikrofon','NumberTitle','off');
            tl  = tiledlayout(fig, 3, Nr, 'TileSpacing','compact', 'Padding','compact');

            for ch = 1:Nr
                MD = MetricDataCell{ch};
                if isempty(MD) || ~isfield(MD,'P') || ~isfield(MD,'R') || ~isfield(MD,'Metric')
                    % Skip invalid entries
                    continue;
                end
                P = MD.P;
                R = MD.R;
                M = MD.Metric;
                if isfield(MD,'idxMax')
                    idxMax = MD.idxMax;
                else
                    [~, idxMax] = max(M);
                end

                % Row 1: correlation |P(d)|
                nexttile(tl, ch);
                plot(abs(P));
                xlabel('d');
                ylabel('|P(d)|');
                title(sprintf('Mic %d: Korrelationssumme', ch));
                grid on;

                % Row 2: energy / power R(d)
                nexttile(tl, Nr + ch);
                plot(R);
                xlabel('d');
                ylabel('R(d)');
                title(sprintf('Mic %d: mittlere Leistung', ch));
                grid on;

                % Row 3: synchronization metric
                nexttile(tl, 2*Nr + ch);
                plot(M); hold on;
                xline(idxMax, 'r--');
                xlabel('d');
                ylabel('M(d)');
                title(sprintf('Mic %d: Rahmensync', ch));
                grid on;
            end
        end

        % Button pushed function: ShowChannelSymbolsButton
        function ShowChannelSymbolsButtonPushed(app, event)
             if isempty(app.RxAnalysisData)
                uialert(app.UIFigure, 'No Rx analysis data available. Please run processing first.', 'No Data');
                return;
            end
            d = app.RxAnalysisData;
        
            if ~isfield(d,'mDataRxEq') || isempty(d.mDataRxEq)
                uialert(app.UIFigure, 'mDataRxEq is empty. Cannot plot constellation.', 'No Symbol Data');
                return;
            end
        
            mEq = d.mDataRxEq;                 % [iNoTxAnt x iNfft x iNoBlocks]
            app.iNoTxAnt = size(mEq,1);
        
            fig = figure('Name','Constellation','NumberTitle','off');
            tg  = uitabgroup(fig);
        
            for tx = 1:app.iNoTxAnt
                tab = uitab(tg, 'Title', sprintf('Tx %d', tx));
                tl  = tiledlayout(tab, 1, 1, 'TileSpacing','compact', 'Padding','compact');
        
                s = squeeze(mEq(tx,:,:));  % [iNfft x iNoBlocks]
                s = s(:);
        
                nexttile(tl,1);
                plot(real(s), imag(s), '.');
                grid on;
                axis equal;
                xlabel('Re'); ylabel('Im');
                title(sprintf('Tx %d Constellation', tx));
            end
        end

        % Button pushed function: ShowCIRCTFButton
        function ShowCIRCTFButtonPushed(app, event)
             if isempty(app.RxAnalysisData)
                uialert(app.UIFigure, 'No Rx analysis data available. Please run processing first.', 'No Data');
                return;
            end
            d = app.RxAnalysisData;
        
            if ~isfield(d,'kanalimpuls') || isempty(d.kanalimpuls) || ...
               ~isfield(d,'kanalUeb')    || isempty(d.kanalUeb)
                uialert(app.UIFigure, 'kanalimpuls / kanalUeb empty. Cannot plot CIR/CTF.', 'No Channel Data');
                return;
            end
        
            h4 = d.kanalimpuls; % expected [Lh x SubFrames x Nr x Nt] (maybe permuted)
            H4 = d.kanalUeb;    % expected [Nsc x SubFrames x Nr x Nt] (maybe permuted)
        
            h4 = app.localNormalizeChan4D(h4);
            H4 =app.localNormalizeChan4D(H4);
        
            [Lh, nSF1, Nr1, Nt1] = size(h4);
            [Nsc,nSF2, Nr2, Nt2] = size(H4);
        
            % basic consistency check
            if Nr1 ~= Nr2 || Nt1 ~= Nt2
                uialert(app.UIFigure, ...
                    sprintf('Dimension mismatch: kanalimpuls=[%d x %d x %d x %d], kanalUeb=[%d x %d x %d x %d].', ...
                    Lh,nSF1,Nr1,Nt1, Nsc,nSF2,Nr2,Nt2), ...
                    'Channel Dim Error');
                return;
            end
        
            nSF = min(nSF1, nSF2);
            sf  = localSelectSubframe(app, nSF, 'Select subframe for CIR+CTF');
            if isempty(sf); return; end
        
            fig = figure('Name', sprintf('CIR + CTF (paired) | Subframe %d', sf), 'NumberTitle','off');
            tg  = uitabgroup(fig);
        
            Nr = Nr1; Nt = Nt1;
        
            % Each Tx speaker -> one tab
            for tx = 1:Nt
                tab = uitab(tg, 'Title', sprintf('Tx %d', tx));
                % In each tab: rows=2 (CIR row, CTF row), cols=Nr mics
                tl = tiledlayout(tab, 2, Nr, 'TileSpacing','compact', 'Padding','compact');
        
                for rx = 1:Nr
                    % --- CIR (top row) ---
                    nexttile(tl, rx);
                    h = squeeze(h4(:,sf,rx,tx));
                    stem(0:Lh-1, abs(h), 'filled');
                    grid on;
                    xlabel('Tap'); ylabel('|h[n]|');
                    title(sprintf('Mic %d: CIR', rx));
        
                    % --- CTF (bottom row) ---
                    nexttile(tl, Nr + rx);
                    H = squeeze(H4(:,sf,rx,tx));
                    plot(20*log10(abs(H)+eps));
                    grid on;
                    xlabel('Subcarrier'); ylabel('|H[k]| [dB]');
                    title(sprintf('Mic %d: CTF', rx));
                end
            end
        end

        % Button pushed function: ShowChannelRankButton
        function ShowChannelRankButtonPushed(app, event)
            d = app.RxAnalysisData;
            if isempty(d) || ~isfield(d,'kanalUeb') || isempty(d.kanalUeb)
                uialert(app.UIFigure,'No kanalUeb (CTF) available. Run RX processing first.','No Data');
                return;
            end
        
            H = d.kanalUeb;  % expected: [Nsc x Nsf x Nr x Nt]
            sz = size(H);
            if numel(sz) < 4
                uialert(app.UIFigure,'kanalUeb must be 4-D: [Nsc x Nsf x Nr x Nt].','Bad Shape');
                return;
            end
            Nsc = sz(1); Nsf = sz(2); Nr = sz(3); Nt = sz(4);
        
            % Compute rank per (subframe, subcarrier)
            rankMat = zeros(Nsc, Nsf);
            svMin   = zeros(Nsc, Nsf); % smallest singular value (optional)
            condEst = zeros(Nsc, Nsf); % condition number estimate (optional)
        
            for sf = 1:Nsf
                for k = 1:Nsc
                    Hk = squeeze(H(k,sf,:,:));   % [Nr x Nt]
                    if any(~isfinite(Hk(:))) || all(abs(Hk(:))<eps)
                        rankMat(k,sf) = 0;
                        svMin(k,sf)   = 0;
                        condEst(k,sf) = NaN;
                        continue;
                    end
                    s = svd(Hk,'econ');
                    % rank threshold relative to max singular value
                    tol = max(size(Hk)) * eps(max(s));
                    rk  = sum(s > tol);
                    rankMat(k,sf) = rk;
        
                    svMin(k,sf) = min(s);
                    if min(s) > 0
                        condEst(k,sf) = max(s)/min(s);
                    else
                        condEst(k,sf) = Inf;
                    end
                end
            end
        
            % ---- UI: tab per subframe ----
            fig = figure('Name','Channel Rank per Subcarrier','NumberTitle','off');
            tg  = uitabgroup(fig);
        
            for sf = 1:Nsf
                tab = uitab(tg,'Title',sprintf('Subframe %d',sf));
        
                % Layout: left curve, right histogram
                gl = uigridlayout(tab,[2 2]);
                gl.RowHeight = {'1x','1x'};
                gl.ColumnWidth = {'1x','1x'};
                gl.Padding = [10 10 10 10];
        
                ax1 = uiaxes(gl); ax1.Layout.Row = 1; ax1.Layout.Column = 1;
                plot(ax1, 1:Nsc, rankMat(:,sf), '-o');
                grid(ax1,'on');
                ylim(ax1, [-0.5, max(Nr,Nt)+0.5]);
                xlabel(ax1,'Subcarrier k'); ylabel(ax1,'rank(H_k)');
                title(ax1,sprintf('Rank vs Subcarrier (Nr=%d, Nt=%d)',Nr,Nt));
        
                ax2 = uiaxes(gl); ax2.Layout.Row = 1; ax2.Layout.Column = 2;
                histogram(ax2, rankMat(:,sf), 'BinMethod','integers');
                grid(ax2,'on');
                xlabel(ax2,'rank'); ylabel(ax2,'count');
                title(ax2,'Rank Histogram');
        
                ax3 = uiaxes(gl); ax3.Layout.Row = 2; ax3.Layout.Column = 1;
                plot(ax3, 1:Nsc, 20*log10(svMin(:,sf)+eps));
                grid(ax3,'on');
                xlabel(ax3,'Subcarrier k'); ylabel(ax3,'min singular value (dB)');
                title(ax3,'Min Singular Value (proxy for ill-conditioning)');
        
                ax4 = uiaxes(gl); ax4.Layout.Row = 2; ax4.Layout.Column = 2;
                plot(ax4, 1:Nsc, log10(condEst(:,sf)+eps));
                grid(ax4,'on');
                xlabel(ax4,'Subcarrier k'); ylabel(ax4,'log10(cond(H_k))');
                title(ax4,'Condition Number (log10)');
            end
        
            % store for later
            app.RxAnalysisData.rankMat = rankMat;
            app.RxAnalysisData.svMin   = svMin;
            app.RxAnalysisData.condEst = condEst;


        end

        % Button pushed function: ShowEVMButton
        function ShowEVMButtonPushed(app, event)
            d = app.RxAnalysisData;
            if isempty(d) || ~isfield(d,'mDataRxEq') || isempty(d.mDataRxEq)
                uialert(app.UIFigure,'No equalized symbols (mDataRxEq) available. Run RX processing first.','No Data');
                return;
            end
            if ~isfield(d,'iModOrd') || isempty(d.iModOrd)
                uialert(app.UIFigure,'Missing iModOrd in RxAnalysisData.','No Param');
                return;
            end
        
            mEq = d.mDataRxEq;                 % [Nt x Nsc x Nblk]
            Nt  = size(mEq,1);
            Nsc = size(mEq,2);
            Nblk= size(mEq,3);
        
            M = 2^d.iModOrd;
        
            % ===================== NEW: exclude BPSK header positions from QAM EVM =====================
            % Determine header length (in symbol positions = bits for BPSK header mapping)
            len_cInfoBits = 0;
            if isfield(d,'len_cInfoBits') && ~isempty(d.len_cInfoBits)
                len_cInfoBits = d.len_cInfoBits;
            elseif isprop(app,'TxMeta') && isstruct(app.TxMeta) && isfield(app.TxMeta,'len_cInfoBits')
                len_cInfoBits = app.TxMeta.len_cInfoBits;
            end
            len_cInfoBits = max(0, round(double(len_cInfoBits)));
        
            % Build header mask in the SAME linear order as TX overwrote symbols in mDataTxFreq:
            % mDataTxFreq is [Nsc x Nblk x Nt], and TX replaced the first len_cInfoBits linear entries with BPSK.
            hdrMask = false(Nsc, Nblk, Nt);
            Ltot = Nsc * Nblk * Nt;
            len_cInfoBits = min(len_cInfoBits, Ltot);
            if len_cInfoBits > 0
                hdrMask(1:len_cInfoBits) = true;
            end
        
            % ---------- EVM metrics (payload-only) ----------
            evmTx_rms = nan(Nt,1);
            evmTx_db  = nan(Nt,1);
            evmSC_db  = nan(Nsc,Nt);
        
            % Also collect per-Tx error magnitudes for histograms (payload-only)
            eMagCell = cell(Nt,1);
        
            for tx = 1:Nt
                S = squeeze(mEq(tx,:,:));          % [Nsc x Nblk]
                S(~isfinite(S)) = 0;
        
                validMaskTx = ~hdrMask(:,:,tx);    % payload positions for this Tx
        
                % ---- RMS EVM over payload only ----
                sPayload = S(validMaskTx);
                if ~isempty(sPayload)
                    idx  = qamdemod(sPayload, M, 'UnitAveragePower', true);
                    sRef = qammod(idx, M, 'UnitAveragePower', true);
                    e    = sPayload - sRef;
        
                    eMagCell{tx} = abs(e);
                    evmTx_rms(tx) = sqrt(mean(abs(e).^2) / (mean(abs(sRef).^2)+eps));
                    evmTx_db(tx)  = 20*log10(evmTx_rms(tx)+eps);
                else
                    eMagCell{tx} = [];
                    evmTx_rms(tx) = NaN;
                    evmTx_db(tx)  = NaN;
                end
        
                % ---- per-subcarrier EVM (payload-only blocks) ----
                for k = 1:Nsc
                    blkMask = validMaskTx(k,:);    % which blocks are payload at this subcarrier
                    sk = S(k, blkMask).';
                    if isempty(sk)
                        evmSC_db(k,tx) = NaN;
                        continue;
                    end
                    idxk = qamdemod(sk, M, 'UnitAveragePower', true);
                    refk = qammod(idxk, M, 'UnitAveragePower', true);
                    ek   = sk - refk;
                    evm_k = sqrt(mean(abs(ek).^2)/(mean(abs(refk).^2)+eps));
                    evmSC_db(k,tx) = 20*log10(evm_k+eps);
                end
            end
        
            % ---------- Common colors per Tx ----------
            txColors = lines(Nt);
        
            % ---------- Global axis limits for constellation (init only) ----------
            allS = mEq(:);
            allS(~isfinite(allS)) = 0;
            rMax = max(abs(real(allS)));
            iMax = max(abs(imag(allS)));
            lim  = 1.05 * max(rMax, iMax);
            if ~isfinite(lim) || lim <= 0, lim = 1; end
            xLim0 = [-lim lim];
            yLim0 = [-lim lim];
        
            % ---------- Unified histogram bin edges (shared across all Tx) ----------
            if Nt >= 1 && any(cellfun(@(c)~isempty(c), eMagCell))
                allE = vertcat(eMagCell{~cellfun(@isempty,eMagCell)});
            else
                allE = 0;
            end
            allE(~isfinite(allE)) = 0;
            if isempty(allE), allE = 0; end
        
            maxE = prctile(allE, 99.5);   % robust against outliers
            if ~isfinite(maxE) || maxE <= 0
                maxE = max(allE);
                if ~isfinite(maxE) || maxE <= 0, maxE = 1; end
            end
            nBins = 160;                  % more x "sampling" via more bins
            edges = linspace(0, maxE, nBins+1);
        
            % ---- UI: Summary + per-Tx tabs ----
            fig = figure('Name','EVM and Constellation Quality','NumberTitle','off');
            tg  = uitabgroup(fig);
        
            % ===================== Summary tab =====================
            tab0 = uitab(tg,'Title','Summary');
            gl0  = uigridlayout(tab0,[2 2]);
            gl0.Padding = [10 10 10 10];
        
            % (A) RMS EVM per Tx (colored bars)
            axA = uiaxes(gl0); axA.Layout.Row = 1; axA.Layout.Column = 1;
            b = bar(axA, evmTx_db, 'FaceColor','flat');
            b.CData = txColors;
            grid(axA,'on'); xlabel(axA,'Tx stream'); ylabel(axA,'EVM (dB)');
            title(axA,'RMS EVM per Tx (payload only)');
        
            % (B) EVM vs Subcarrier (per Tx) with matching colors
            axB = uiaxes(gl0); axB.Layout.Row = 1; axB.Layout.Column = 2;
            hold(axB,'on');
            for tx = 1:Nt
                plot(axB, 1:Nsc, evmSC_db(:,tx), 'LineWidth', 1.2, 'Color', txColors(tx,:));
            end
            hold(axB,'off');
            grid(axB,'on'); xlabel(axB,'Subcarrier k'); ylabel(axB,'EVM (dB)');
            title(axB,'EVM vs Subcarrier (per Tx) (payload only)');
            legend(axB, arrayfun(@(x)sprintf('Tx%d',x),1:Nt,'UniformOutput',false), 'Location','best');
        
            % (C) Histogram overview: stack all Tx in one axes
            axC = uiaxes(gl0); axC.Layout.Row = 2; axC.Layout.Column = 1;
            hold(axC,'on');
            for tx = 1:Nt
                if ~isempty(eMagCell{tx})
                    histogram(axC, eMagCell{tx}, 'BinEdges', edges, ...
                        'DisplayStyle','stairs', 'EdgeColor', txColors(tx,:));
                end
            end
            hold(axC,'off');
            grid(axC,'on'); xlim(axC,[0 maxE]);
            xlabel(axC,'|e|'); ylabel(axC,'Count');
            title(axC,'Error magnitude histograms (all Tx, payload only)');
            legend(axC, arrayfun(@(x)sprintf('Tx%d',x),1:Nt,'UniformOutput',false), 'Location','best');
        
            % (D) Text summary
            axD = uiaxes(gl0); axD.Layout.Row = 2; axD.Layout.Column = 2;
            text(axD, 0.05, 0.92, sprintf('Nsc=%d, Nblk=%d, M=%d', Nsc, Nblk, M), 'Units','normalized');
            text(axD, 0.05, 0.82, sprintf('Excluded header: len_cInfoBits=%d', len_cInfoBits), 'Units','normalized');
            for tx=1:Nt
                text(axD, 0.05, 0.82-0.09*tx, sprintf('Tx%d EVM=%.2f dB', tx, evmTx_db(tx)), 'Units','normalized');
            end
            axis(axD,'off');
        
            % ===================== Per-Tx tabs =====================
            for tx = 1:Nt
                tab = uitab(tg,'Title',sprintf('Tx%d',tx));
                gl  = uigridlayout(tab,[2 2]);
                gl.Padding = [10 10 10 10];
                gl.RowHeight = {'1x','1x'};
                gl.ColumnWidth = {'1x','1x'};
        
                % (1) Constellation (fixed initial axes, same for all Tx)
                ax1 = uiaxes(gl); ax1.Layout.Row = 1; ax1.Layout.Column = 1;
                sAll = squeeze(mEq(tx,:,:)); sAll = sAll(:); sAll(~isfinite(sAll))=0;
                plot(ax1, real(sAll), imag(sAll), '.', 'Color', txColors(tx,:));
                axis(ax1,'equal'); grid(ax1,'on');
                xlim(ax1, xLim0); ylim(ax1, yLim0);
                xlabel(ax1,'Re'); ylabel(ax1,'Im');
                title(ax1,sprintf('Constellation Tx%d (all symbols)',tx));
        
                % (2) EVM vs Subcarrier (matching color)
                ax2 = uiaxes(gl); ax2.Layout.Row = 1; ax2.Layout.Column = 2;
                plot(ax2, 1:Nsc, evmSC_db(:,tx), 'LineWidth', 1.2, 'Color', txColors(tx,:));
                grid(ax2,'on');
                xlabel(ax2,'Subcarrier k'); ylabel(ax2,'EVM (dB)');
                title(ax2,sprintf('EVM vs Subcarrier (Tx%d, payload only)',tx));
        
                % (3) Error magnitude histogram for this Tx (payload-only + shared edges)
                ax3 = uiaxes(gl); ax3.Layout.Row = 2; ax3.Layout.Column = 1;
                if ~isempty(eMagCell{tx})
                    histogram(ax3, eMagCell{tx}, 'BinEdges', edges, 'FaceColor', txColors(tx,:));
                else
                    histogram(ax3, 0, 'BinEdges', edges, 'FaceColor', txColors(tx,:));
                end
                grid(ax3,'on'); xlim(ax3,[0 maxE]);
                xlabel(ax3,'|e|'); ylabel(ax3,'Count');
                title(ax3,sprintf('Error |e| histogram (Tx%d, payload only)',tx));
        
                % (4) Small stats panel
                ax4 = uiaxes(gl); ax4.Layout.Row = 2; ax4.Layout.Column = 2;
                text(ax4, 0.05, 0.92, sprintf('Tx%d',tx), 'Units','normalized', 'FontWeight','bold');
                text(ax4, 0.05, 0.78, sprintf('RMS EVM: %.2f dB', evmTx_db(tx)), 'Units','normalized');
                if ~isempty(eMagCell{tx})
                    text(ax4, 0.05, 0.64, sprintf('Median |e|: %.4g', median(eMagCell{tx})), 'Units','normalized');
                    text(ax4, 0.05, 0.50, sprintf('95%% |e|: %.4g', prctile(eMagCell{tx},95)), 'Units','normalized');
                end
                axis(ax4,'off');
        
                % Enable default interactions (zoom/pan) per axes
                try
                    enableDefaultInteractivity(ax1);
                    enableDefaultInteractivity(ax2);
                    enableDefaultInteractivity(ax3);
                catch
                    % ignore if not available in older releases
                end
            end
        
            % store for later
            app.RxAnalysisData.evmTx_db = evmTx_db;
            app.RxAnalysisData.evmSC_db = evmSC_db;
        end

        % Button pushed function: ShowKeyMetricsButton
        function ShowKeyMetricsButtonPushed(app, event)
            d = app.RxAnalysisData;
            if isempty(d)
                uialert(app.UIFigure,'No RxAnalysisData available. Run RX processing first.','No Data');
                return;
            end
        
            rows = {};
        
            % Parameters (if present)
            rows = app.addKV(rows,'NFFT',         app.getField(d,'iNfft'));
            rows = app.addKV(rows,'CP (Ng)',      app.getField(d,'iNg'));
            rows = app.addKV(rows,'ModOrd',       app.getField(d,'iModOrd'));
            rows = app.addKV(rows,'Tx Ant (Nt)',  app.getField(d,'iNoTxAnt'));
            rows = app.addKV(rows,'Rx Ant (Nr)',  app.getField(d,'iNoRxAnt'));
        
            % Sync/CFO metrics if stored
            if isfield(d,'MetricData') && ~isempty(d.MetricData)
                rows = app.addKV(rows,'MetricData channels', numel(d.MetricData));
                % try pull idxMax from first mic
                try
                    md1 = d.MetricData{1};
                    if isfield(md1,'idxMax'), rows = app.addKV(rows,'FrameStart idxMax (mic1)', md1.idxMax); end
                catch
                end
            end
        
            % If you stored CFOs/frameStart elsewhere, show them
            rows = app.addKV(rows,'CFO (median/used)', app.getField(d,'fCfoUsed'));
            rows = app.addKV(rows,'FrameStart used',   app.getField(d,'iFrStartUsed'));
        
            % SNR / iSNR if you stored them
            rows = app.addKV(rows,'iSNR (linear)', app.getField(d,'iSNR'));
            if isfield(d,'iSNR') && ~isempty(d.iSNR) && isfinite(d.iSNR) && d.iSNR>0
                rows = app.addKV(rows,'SNR (dB)', 10*log10(d.iSNR));
            end
        
            % EVM if available
            if isfield(d,'evmTx_db')
                for tx = 1:numel(d.evmTx_db)
                    rows = app.addKV(rows, sprintf('EVM Tx%d (dB)',tx), d.evmTx_db(tx));
                end
            end
        
            % Rank summary if available
            if isfield(d,'rankMat')
                rm = d.rankMat;
                rows = app.addKV(rows,'Rank mean', mean(rm(:)));
                rows = app.addKV(rows,'Rank min',  min(rm(:)));
                rows = app.addKV(rows,'Rank max',  max(rm(:)));
            end
        
            % Build table
            T = cell2table(rows, 'VariableNames', {'Metric','Value'});
        
            fig = figure('Name','Key Metrics','NumberTitle','off');
            uit = uitable(fig, 'Data', T{:,:}, 'ColumnName', T.Properties.VariableNames);
            uit.Units = 'normalized';
            uit.Position = [0 0 1 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SystemParametersPanel = uipanel(app.UIFigure);
            app.SystemParametersPanel.Title = 'SystemParametersPanel';
            app.FFTLengthLabel = uilabel(app.SystemParametersPanel);
            app.FFTLengthLabel.Text = 'FFT Length';
            app.FFTLengthDropDown = uidropdown(app.SystemParametersPanel);
            app.FFTLengthDropDown.Items = {'8', '16', '32', '64', '128', '256', '512', '1024'};
            app.FFTLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTLengthDropDownValueChanged, true);
            app.FFTLengthDropDown.Value = '128';
            app.CPLengthLabel = uilabel(app.SystemParametersPanel);
            app.CPLengthLabel.Text = 'CP Length';
            app.CPLengthEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.CPLengthEditField.ValueChangedFcn = createCallbackFcn(app, @CPLengthEditFieldValueChanged, true);
            app.TxAntennasLabel = uilabel(app.SystemParametersPanel);
            app.TxAntennasLabel.Text = 'Tx Antennas';
            app.TxAntennasEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.TxAntennasEditField.ValueChangedFcn = createCallbackFcn(app, @TxAntennasEditFieldValueChanged, true);
            app.RxAntennasLabel = uilabel(app.SystemParametersPanel);
            app.RxAntennasLabel.Text = 'Rx Antennas';
            app.RxAntennasEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.RxAntennasEditField.ValueChangedFcn = createCallbackFcn(app, @RxAntennasEditFieldValueChanged, true);
            app.CarrierFrequencyLabel = uilabel(app.SystemParametersPanel);
            app.CarrierFrequencyLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.CarrierFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyEditFieldValueChanged, true);
            app.ModulationOrderLabel = uilabel(app.SystemParametersPanel);
            app.ModulationOrderLabel.Text = 'Modulation Order';
            app.ModulationOrderDropDown = uidropdown(app.SystemParametersPanel);
            app.ModulationOrderDropDown.Items = {'BPSK', '4QAM', '8QAM', '16QAM', '32QAM', '64QAM'};
            app.ModulationOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationOrderDropDownValueChanged, true);
            app.ModulationOrderDropDown.Value = 'BPSK';
            app.DACSamplingRateLabel = uilabel(app.SystemParametersPanel);
            app.DACSamplingRateLabel.Text = 'DAC Sampling Rate';
            app.DACSamplingRateEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.DACSamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @DACSamplingRateEditFieldValueChanged, true);
            app.AnzahlderBlckeEditFieldLabel = uilabel(app.SystemParametersPanel);
            app.AnzahlderBlckeEditFieldLabel.Text = 'Anzahl der Blöcke';
            app.AnzahlderBlckeEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.AnzahlderBlckeEditField.ValueChangedFcn = createCallbackFcn(app, @AnzahlderBlckeEditFieldValueChanged, true);
            app.AnzahlderSubBlckeEditFieldLabel = uilabel(app.SystemParametersPanel);
            app.AnzahlderSubBlckeEditFieldLabel.Text = 'Anzahl der Sub-Blöcke';
            app.AnzahlderSubBlckeEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.AnzahlderSubBlckeEditField.ValueChangedFcn = createCallbackFcn(app, @AnzahlderSubBlckeEditFieldValueChanged, true);
            app.DauerderbertragungEditFieldLabel = uilabel(app.SystemParametersPanel);
            app.DauerderbertragungEditFieldLabel.Text = 'Dauer der Übertragung';
            app.DauerderbertragungEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.BasebandFrequencyEditFieldLabel = uilabel(app.SystemParametersPanel);
            app.BasebandFrequencyEditFieldLabel.Text = 'Baseband Frequency';
            app.BasebandFrequencyEditField = uieditfield(app.SystemParametersPanel, 'numeric');
            app.BasebandFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @BasebandFrequencyEditFieldValueChanged, true);
            app.CodingMIMOPanel = uipanel(app.UIFigure);
            app.CodingMIMOPanel.Title = 'CodingMIMOPanel';
            app.ChannelCodingLabel = uilabel(app.CodingMIMOPanel);
            app.ChannelCodingLabel.Text = 'Channel Coding';
            app.ChannelCodingDropDown = uidropdown(app.CodingMIMOPanel);
            app.ChannelCodingDropDown.Items = {'Keine'};
            app.ChannelCodingDropDown.Enable = 'off';
            app.ChannelCodingDropDown.Value = 'Keine';
            app.MIMOSchemeLabel = uilabel(app.CodingMIMOPanel);
            app.MIMOSchemeLabel.Text = 'MIMO Scheme';
            app.MIMOSchemeDropDown = uidropdown(app.CodingMIMOPanel);
            app.MIMOSchemeDropDown.Items = {'Spatial Multiplexing', 'EigenMode', 'Alamouti', 'V-BLAST'};
            app.MIMOSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @MIMOSchemeDropDownValueChanged, true);
            app.MIMOSchemeDropDown.Value = 'Spatial Multiplexing';
            app.ChannelEstimatorLabel = uilabel(app.CodingMIMOPanel);
            app.ChannelEstimatorLabel.Text = 'Channel Estimator';
            app.ChannelEstimatorDropDown = uidropdown(app.CodingMIMOPanel);
            app.ChannelEstimatorDropDown.Items = {'Zero Forcing', 'MMSE'};
            app.ChannelEstimatorDropDown.Enable = 'off';
            app.ChannelEstimatorDropDown.Value = 'Zero Forcing';
            app.ChannelEqualizerDropDownLabel = uilabel(app.CodingMIMOPanel);
            app.ChannelEqualizerDropDownLabel.Text = 'Channel Equalizer';
            app.ChannelEqualizerDropDown = uidropdown(app.CodingMIMOPanel);
            app.ChannelEqualizerDropDown.Items = {'Zero Forcing', 'MMSE', 'Classical SVD'};
            app.ChannelEqualizerDropDown.Enable = 'off';
            app.ChannelEqualizerDropDown.Value = 'MMSE';
            app.DataSelectionPanel = uipanel(app.UIFigure);
            app.DataSelectionPanel.Title = 'DataSelectionPanel';
            app.SelectFileButton = uibutton(app.DataSelectionPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FilePathLabel = uilabel(app.DataSelectionPanel);
            app.FilePathLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.DataSelectionPanel, 'text');
            app.FileTypeisLabel = uilabel(app.DataSelectionPanel);
            app.FileTypeisLabel.Text = 'File Type is';
            app.FileTypeLabel = uilabel(app.DataSelectionPanel);
            app.FileTypeLabel.Text = 'FileType';
            app.TransmissionStatusLabel = uilabel(app.DataSelectionPanel);
            app.TransmissionStatusLabel.Text = 'Transmission Status :';
            app.TransmissionStatusLabel_2 = uilabel(app.DataSelectionPanel);
            app.TransmissionStatusLabel_2.Text = 'Transmission Status';
            app.StartTransmissionButton = uibutton(app.DataSelectionPanel, 'push');
            app.StartTransmissionButton.ButtonPushedFcn = createCallbackFcn(app, @StartTransmissionButtonPushed, true);
            app.StartTransmissionButton.Text = 'Start Transmission';
            app.SentfilesLabel = uilabel(app.DataSelectionPanel);
            app.SentfilesLabel.Text = 'Sent files';
            app.DataPreviewTextArea = uitextarea(app.DataSelectionPanel);
            app.RecommendedBlocksandSubBlocksLabel = uilabel(app.DataSelectionPanel);
            app.RecommendedBlocksandSubBlocksLabel.Text = 'Recommended Blocks and Sub-Blocks';
            app.ReceivePanel = uipanel(app.UIFigure);
            app.ReceivePanel.Title = 'ReceivePanel';
            app.StartProcessingButton = uibutton(app.ReceivePanel, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'StartProcessingButton';
            app.BitErrorCountLabel = uilabel(app.ReceivePanel);
            app.BitErrorCountLabel.Text = 'BitErrorCountLabel';
            app.BitErrorCountValueLabel = uilabel(app.ReceivePanel);
            app.BitErrorCountValueLabel.Text = 'BitErrorCountValueLabel';
            app.BERLabel = uilabel(app.ReceivePanel);
            app.BERLabel.Text = 'BERLabel';
            app.BERValueLabel = uilabel(app.ReceivePanel);
            app.BERValueLabel.Text = 'BERValueLabel';
            app.ReceivedfilesLabel = uilabel(app.ReceivePanel);
            app.ReceivedfilesLabel.Text = 'Received files';
            app.ReceivedPreviewTextArea = uitextarea(app.ReceivePanel);
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis Panel';
            app.ShowRxSignalButton = uibutton(app.AnalysisPanel, 'push');
            app.ShowRxSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRxSignalButtonPushed, true);
            app.ShowRxSignalButton.Text = 'Show Rx Signal';
            app.ShowCIRCTFButton = uibutton(app.AnalysisPanel, 'push');
            app.ShowCIRCTFButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCIRCTFButtonPushed, true);
            app.ShowCIRCTFButton.Text = 'Show CIR CTF';
            app.ShowChannelSymbolsButton = uibutton(app.AnalysisPanel, 'push');
            app.ShowChannelSymbolsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowChannelSymbolsButtonPushed, true);
            app.ShowChannelSymbolsButton.Text = 'Show Channel Symbols';
            app.ShowSynchronizationButton = uibutton(app.AnalysisPanel, 'push');
            app.ShowSynchronizationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSynchronizationButtonPushed, true);
            app.ShowSynchronizationButton.Text = 'Show Synchronization';
            app.ShowChannelRankButton = uibutton(app.AnalysisPanel, 'push');
            app.ShowChannelRankButton.ButtonPushedFcn = createCallbackFcn(app, @ShowChannelRankButtonPushed, true);
            app.ShowChannelRankButton.Text = 'Show Channel Rank';
            app.ShowEVMButton = uibutton(app.AnalysisPanel, 'push');
            app.ShowEVMButton.ButtonPushedFcn = createCallbackFcn(app, @ShowEVMButtonPushed, true);
            app.ShowEVMButton.Text = 'Show EVM';
            app.ShowKeyMetricsButton = uibutton(app.AnalysisPanel, 'push');
            app.ShowKeyMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowKeyMetricsButtonPushed, true);
            app.ShowKeyMetricsButton.Text = 'Show Key Metrics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gengqi0107

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end